classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        detHEditField    matlab.ui.control.NumericEditField
        detHLabel        matlab.ui.control.Label
        tEditField       matlab.ui.control.NumericEditField
        tEditFieldLabel  matlab.ui.control.Label
        CheckButton      matlab.ui.control.Button
        Slider           matlab.ui.control.Slider
        SliderLabel      matlab.ui.control.Label
        RightPanel       matlab.ui.container.Panel
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global H H2 x y;
            H=load("test.mat","H","H2").H;
            H2=load("test.mat","H","H2").H2;
            N=100;
            x=(0:N)/N;
            y=zeros(0,N+1);
            for i = 1 : N+1
                y(i)=det(x(i)*H+(1-x(i))*(H2));
            end
            plot(app.UIAxes,x,y);
            grid(app.UIAxes,"minor");

            imagesc(app.UIAxes2,H2);
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            global H H2 x y;
            plot(app.UIAxes,x,y);
            hold(app.UIAxes,"on");
            t=app.Slider.Value;
            app.tEditField.Value=t;
            K=t*H+(1-t)*(H2);
            plot(app.UIAxes,[t],[det(K)],'o');
            hold(app.UIAxes,"off");
            
            app.detHEditField.Value=det(K);
            imagesc(app.UIAxes2,K);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [0 1];
            app.CheckButton = uibutton(app.LeftPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.tEditFieldLabel = uilabel(app.LeftPanel);
            app.tEditFieldLabel.Text = 't=';
            app.tEditField = uieditfield(app.LeftPanel, 'numeric');
            app.detHLabel = uilabel(app.LeftPanel);
            app.detHLabel.Text = 'detH=';
            app.detHEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'det(tH41+(1-t)H42)')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'detH')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIAxes2 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            colormap(app.UIAxes2, 'gray')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end